classdef TestGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
    end

    
    methods (Access = private)
        
        function main(app, action)
%             run = false;
%             if (strcmp(action, 'run') == true)
%                 run = true;
%             elseif (strcmp(action, 'close') == true)
%                 run = false;
%                 pause(inf);
%                 return;
%             end
% 
%             seconds = 0;
%             minutes = 0;
%             hours = 0;
%             
%             while (run == true)
%                 fprintf('Runtime: %d hours %d minutes %d seconds.\n', hours, minutes, seconds)
%                 seconds = seconds + 1;
%                 if (seconds == 60)
%                     minutes = minutes + 1;
%                     if (minutes == 60)
%                         hours = hours + 1;
%                         minutes = 0;
%                     end
%                     seconds = 0;
%                 end
%                 pause(1);
%             end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             app.main('run');
            disp('startupFcn test GUI')

            seconds = 0;
            minutes = 0;
            hours = 0;

            while isvalid(app)
                fprintf('Runtime: %d hours %d minutes %d seconds.\n', hours, minutes, seconds)
                seconds = seconds + 1;
                if (seconds == 60)
                    minutes = minutes + 1;
                    if (minutes == 60)
                        hours = hours + 1;
                        minutes = 0;
                    end
                    seconds = 0;
                end
                pause(1);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TestGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end