classdef NodeCommandsTestGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ListOfCOMPorts       matlab.ui.control.ListBox
        TestcommandsListBox  matlab.ui.control.ListBox
        SerialCommands       matlab.ui.control.EditField
        Button               matlab.ui.control.Button
    end

    
    properties (Access = public)
        serial; % Description
    end
    
    methods (Access = private)
        
        function displayCOMPorts(app)
            COMPorts = serialportlist();
            app.ListOfCOMPorts.Items = COMPorts;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.displayCOMPorts();
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            COMPort = app.ListOfCOMPorts.Value;
            if (app.TestcommandsListBox.Value == "Serial send")
                serialSend(app.serial, app.SerialCommands.Value); % send the data in the EditField to the master of the nodes.
            elseif (app.TestcommandsListBox.Value == "Serial receive")
                % add serial receive function.
            elseif (app.TestcommandsListBox.Value == "Measurement")
                serialSend(app.serial, 'M');
            elseif (app.TestcommandsListBox.Value == "Localization")
                serialSend(app.serial, 'C');
            elseif (app.TestcommandsListBox.Value == "Test nodes")
                serialSend(app.serial, 'T');
            elseif (app.TestcommandsListBox.Value == "Open COMPort") %open the com port and set right settings
                app.serial = initiateSerialPort(COMPort, 1); %number of nodes/boards = 1
                disp(app.serial)
            elseif (app.TestcommandsListBox.Value == "Close COMPort")
                % add close com port function.
            end
        end

        % Value changed function: SerialCommands
        function SerialCommandsValueChanged(app, event)
            app.SerialCommands.Value = app.SerialCommands.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.SerialCommands = uieditfield(app.UIFigure, 'text');
            app.SerialCommands.ValueChangedFcn = createCallbackFcn(app, @SerialCommandsValueChanged, true);
            app.TestcommandsListBox = uilistbox(app.UIFigure);
            app.TestcommandsListBox.Items = {'Serial send', 'Serial receive', 'Measurement', 'Localization', 'Test nodes', 'Open COMPort', 'Close COMPort'};
            app.TestcommandsListBox.Value = 'Test nodes';
            app.ListOfCOMPorts = uilistbox(app.UIFigure);
            app.ListOfCOMPorts.Items = {'COM ports:'};
            app.ListOfCOMPorts.Value = 'COM ports:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NodeCommandsTestGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end